classdef Experiment < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        k_len5EditField              matlab.ui.control.NumericEditField
        k_len5EditFieldLabel         matlab.ui.control.Label
        alpha5EditField              matlab.ui.control.NumericEditField
        alpha5EditFieldLabel         matlab.ui.control.Label
        theta5EditField              matlab.ui.control.NumericEditField
        theta5EditFieldLabel         matlab.ui.control.Label
        k_len4EditField              matlab.ui.control.NumericEditField
        k_len4EditFieldLabel         matlab.ui.control.Label
        alpha4EditField              matlab.ui.control.NumericEditField
        alpha4EditFieldLabel         matlab.ui.control.Label
        theta4EditField              matlab.ui.control.NumericEditField
        theta4EditFieldLabel         matlab.ui.control.Label
        k_len3EditField              matlab.ui.control.NumericEditField
        k_len3EditFieldLabel         matlab.ui.control.Label
        alpha3EditField              matlab.ui.control.NumericEditField
        alpha3EditFieldLabel         matlab.ui.control.Label
        theta3EditField              matlab.ui.control.NumericEditField
        theta3EditFieldLabel         matlab.ui.control.Label
        k_len2EditField              matlab.ui.control.NumericEditField
        k_len2EditFieldLabel         matlab.ui.control.Label
        alpha2EditField              matlab.ui.control.NumericEditField
        alpha2EditFieldLabel         matlab.ui.control.Label
        theta2EditField              matlab.ui.control.NumericEditField
        theta2EditFieldLabel         matlab.ui.control.Label
        RunButton                    matlab.ui.control.StateButton
        k_len1EditField              matlab.ui.control.NumericEditField
        k_len1EditFieldLabel         matlab.ui.control.Label
        alpha1EditField              matlab.ui.control.NumericEditField
        alpha1EditFieldLabel         matlab.ui.control.Label
        theta1EditField              matlab.ui.control.NumericEditField
        theta1EditFieldLabel         matlab.ui.control.Label
        closeMaskConstant2EditField  matlab.ui.control.NumericEditField
        closeMaskConstant2EditFieldLabel  matlab.ui.control.Label
        closeMaskConstantEditField   matlab.ui.control.NumericEditField
        closeMaskConstantEditFieldLabel  matlab.ui.control.Label
        resEditField                 matlab.ui.control.NumericEditField
        resEditFieldLabel            matlab.ui.control.Label
        Bild5EditField               matlab.ui.control.EditField
        Bild5EditFieldLabel          matlab.ui.control.Label
        Bild4EditField               matlab.ui.control.EditField
        Bild4EditFieldLabel          matlab.ui.control.Label
        Bild3EditField               matlab.ui.control.EditField
        Bild3EditFieldLabel          matlab.ui.control.Label
        Bild2EditField               matlab.ui.control.EditField
        Bild2EditFieldLabel          matlab.ui.control.Label
        Bild1EditField               matlab.ui.control.EditField
        Bild1EditFieldLabel          matlab.ui.control.Label
        ID5EditField                 matlab.ui.control.NumericEditField
        ID5EditFieldLabel            matlab.ui.control.Label
        ID4EditField                 matlab.ui.control.NumericEditField
        ID4EditFieldLabel            matlab.ui.control.Label
        ID3EditField                 matlab.ui.control.NumericEditField
        ID3EditFieldLabel            matlab.ui.control.Label
        ID2EditField                 matlab.ui.control.NumericEditField
        ID2EditFieldLabel            matlab.ui.control.Label
        ID1EditField                 matlab.ui.control.NumericEditField
        ID1EditFieldLabel            matlab.ui.control.Label
        keyProfile5                  matlab.ui.control.UIAxes
        keyProfile4                  matlab.ui.control.UIAxes
        keyProfile3                  matlab.ui.control.UIAxes
        keyProfile2                  matlab.ui.control.UIAxes
        keyProfile1                  matlab.ui.control.UIAxes
        Bild5                        matlab.ui.control.UIAxes
        Auswahlbild5                 matlab.ui.control.UIAxes
        Bild4                        matlab.ui.control.UIAxes
        Auswahlbild4                 matlab.ui.control.UIAxes
        Bild3                        matlab.ui.control.UIAxes
        Auswahlbild3                 matlab.ui.control.UIAxes
        Bild2                        matlab.ui.control.UIAxes
        Auswahlbild2                 matlab.ui.control.UIAxes
        Bild1                        matlab.ui.control.UIAxes
        Auswahlbild1                 matlab.ui.control.UIAxes
        ContextMenu                  matlab.ui.container.ContextMenu
        RunMenu                      matlab.ui.container.Menu
        PlotndernMenu                matlab.ui.container.Menu
        FrameCutMaskMenu             matlab.ui.container.Menu
        FrameCutMenu                 matlab.ui.container.Menu
        FrameKeyMenu                 matlab.ui.container.Menu
        FrameKeyMaskMenu             matlab.ui.container.Menu
        FrameRotMenu                 matlab.ui.container.Menu
        FrameSobelMenu               matlab.ui.container.Menu
        FrameLabelMenu               matlab.ui.container.Menu
    end

    
    properties (Access = private)
        Property % Description
        Key
        Imseleket = "FrameCutMask"
    end
    
    methods (Access = private)
        
        function dispkey(app)
            % Show Frame
            imshow(app.Key(1).Frame,[],"parent",app.Bild1)
            imshow(app.Key(2).Frame,[],"parent",app.Bild2)
            imshow(app.Key(3).Frame,[],"parent",app.Bild3)
            imshow(app.Key(4).Frame,[],"parent",app.Bild4)
            imshow(app.Key(5).Frame,[],"parent",app.Bild5)

            % Show selectet
            dispkeyimwahl(app)

            extr1=app.Key(1).extr;
            extr2=app.Key(2).extr;
            extr3=app.Key(3).extr;
            extr4=app.Key(4).extr;
            extr5=app.Key(5).extr;

            % Show Profile 1
            plot(app.Key(1).keyProfile,"parent",app.keyProfile1)
            hold(app.keyProfile1,"on")
            plot(extr1(:,1),extr1(:,2),"*","parent",app.keyProfile1)
            hold(app.keyProfile1,"off")

            % Show Profile 2
            plot(app.Key(2).keyProfile,"parent",app.keyProfile2)
            hold(app.keyProfile2,"on")
            plot(extr2(:,1),extr2(:,2),"*","parent",app.keyProfile2)
            hold(app.keyProfile2,"off")

            % Show Profile 3
            plot(app.Key(3).keyProfile,"parent",app.keyProfile3)
            hold(app.keyProfile3,"on")
            plot(extr3(:,1),extr3(:,2),"*","parent",app.keyProfile3)
            hold(app.keyProfile3,"off")

            % Show Profile 4
            plot(app.Key(4).keyProfile,"parent",app.keyProfile4)
            hold(app.keyProfile4,"on")
            plot(extr4(:,1),extr4(:,2),"*","parent",app.keyProfile4)
            hold(app.keyProfile4,"off")
 
            % Show Profile 5
            plot(app.Key(5).keyProfile,"parent",app.keyProfile5)
            hold(app.keyProfile5,"on")
            plot(extr5(:,1),extr5(:,2),"*","parent",app.keyProfile5)
            hold(app.keyProfile5,"off")

            % Alpha
            app.alpha1EditField.Value = app.Key(1).alpha;
            app.alpha2EditField.Value = app.Key(2).alpha;
            app.alpha3EditField.Value = app.Key(3).alpha;
            app.alpha4EditField.Value = app.Key(4).alpha;
            app.alpha5EditField.Value = app.Key(5).alpha;

            % Theta
            app.theta1EditField.Value = app.Key(1).theta;
            app.theta2EditField.Value = app.Key(2).theta;
            app.theta3EditField.Value = app.Key(3).theta;
            app.theta4EditField.Value = app.Key(4).theta;
            app.theta5EditField.Value = app.Key(5).theta;

            % k_len
            app.k_len1EditField.Value = app.Key(1).k_len;
            app.k_len2EditField.Value = app.Key(2).k_len;
            app.k_len3EditField.Value = app.Key(3).k_len;
            app.k_len4EditField.Value = app.Key(4).k_len;
            app.k_len5EditField.Value = app.Key(5).k_len;

            % ID
            app.ID1EditField.Value = app.Key(1).ID;
            app.ID2EditField.Value = app.Key(2).ID;
            app.ID3EditField.Value = app.Key(3).ID;
            app.ID4EditField.Value = app.Key(4).ID;
            app.ID5EditField.Value = app.Key(5).ID;

        end
        
        function calc(app)
            app.RunButton.Value = 1;

            app.Key(1).Frame = imread(app.Bild1EditField.Value);
            app.Key(2).Frame = imread(app.Bild2EditField.Value);
            app.Key(3).Frame = imread(app.Bild3EditField.Value);
            app.Key(4).Frame = imread(app.Bild4EditField.Value);
            app.Key(5).Frame = imread(app.Bild5EditField.Value);

            for i=1:5
                app.Key(i) = app.Key(i).calculate;
            end

            dispkey(app)

            app.RunButton.Value = 0;
        end
        
        function dispkeyimwahl(app)
            switch app.Imseleket
                case "FrameCutMask"
                    imshow(app.Key(1).FrameCutMask,[],"parent",app.Auswahlbild1)
                    imshow(app.Key(2).FrameCutMask,[],"parent",app.Auswahlbild2)
                    imshow(app.Key(3).FrameCutMask,[],"parent",app.Auswahlbild3)
                    imshow(app.Key(4).FrameCutMask,[],"parent",app.Auswahlbild4)
                    imshow(app.Key(5).FrameCutMask,[],"parent",app.Auswahlbild5)
                case "FrameCut"
                    imshow(app.Key(1).FrameCut,[],"parent",app.Auswahlbild1)
                    imshow(app.Key(2).FrameCut,[],"parent",app.Auswahlbild2)
                    imshow(app.Key(3).FrameCut,[],"parent",app.Auswahlbild3)
                    imshow(app.Key(4).FrameCut,[],"parent",app.Auswahlbild4)
                    imshow(app.Key(5).FrameCut,[],"parent",app.Auswahlbild5)
                case "FrameKey"
                    imshow(app.Key(1).FrameKey,[],"parent",app.Auswahlbild1)
                    imshow(app.Key(2).FrameKey,[],"parent",app.Auswahlbild2)
                    imshow(app.Key(3).FrameKey,[],"parent",app.Auswahlbild3)
                    imshow(app.Key(4).FrameKey,[],"parent",app.Auswahlbild4)
                    imshow(app.Key(5).FrameKey,[],"parent",app.Auswahlbild5)
                case "FrameKeyMask"
                    imshow(app.Key(1).FrameKeyMask,[],"parent",app.Auswahlbild1)
                    imshow(app.Key(2).FrameKeyMask,[],"parent",app.Auswahlbild2)
                    imshow(app.Key(3).FrameKeyMask,[],"parent",app.Auswahlbild3)
                    imshow(app.Key(4).FrameKeyMask,[],"parent",app.Auswahlbild4)
                    imshow(app.Key(5).FrameKeyMask,[],"parent",app.Auswahlbild5)
                case "FrameRot"
                    imshow(app.Key(1).FrameRot,[],"parent",app.Auswahlbild1)
                    imshow(app.Key(2).FrameRot,[],"parent",app.Auswahlbild2)
                    imshow(app.Key(3).FrameRot,[],"parent",app.Auswahlbild3)
                    imshow(app.Key(4).FrameRot,[],"parent",app.Auswahlbild4)
                    imshow(app.Key(5).FrameRot,[],"parent",app.Auswahlbild5)
                case "FrameSobel"
                    imshow(app.Key(1).FrameSobel,[],"parent",app.Auswahlbild1)
                    imshow(app.Key(2).FrameSobel,[],"parent",app.Auswahlbild2)
                    imshow(app.Key(3).FrameSobel,[],"parent",app.Auswahlbild3)
                    imshow(app.Key(4).FrameSobel,[],"parent",app.Auswahlbild4)
                    imshow(app.Key(5).FrameSobel,[],"parent",app.Auswahlbild5)
                case "FrameLabel"
                    imshow(app.Key(1).FrameLabel,[],"parent",app.Auswahlbild1)
                    imshow(app.Key(2).FrameLabel,[],"parent",app.Auswahlbild2)
                    imshow(app.Key(3).FrameLabel,[],"parent",app.Auswahlbild3)
                    imshow(app.Key(4).FrameLabel,[],"parent",app.Auswahlbild4)
                    imshow(app.Key(5).FrameLabel,[],"parent",app.Auswahlbild5)
            end
            
            app.Auswahlbild1.Title.String=app.Imseleket;
            app.Auswahlbild2.Title.String=app.Imseleket;
            app.Auswahlbild3.Title.String=app.Imseleket;
            app.Auswahlbild4.Title.String=app.Imseleket;
            app.Auswahlbild5.Title.String=app.Imseleket;

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Key=idKey();
            app.Key(2:5)=idKey();
            app.resEditField.Value=app.Key(1).res;
            app.closeMaskConstantEditField.Value=app.Key(1).closeMaskConstant;
            app.closeMaskConstant2EditField.Value=app.Key(1).closeMaskConstant2;
        end

        % Menu selected function: FrameCutMaskMenu
        function FrameCutMaskMenuSelected(app, event)
            app.Imseleket = "FrameCutMask";
            dispkeyimwahl(app)
        end

        % Menu selected function: FrameCutMenu
        function FrameCutMenuSelected(app, event)
            app.Imseleket = "FrameCut";
            dispkeyimwahl(app)
        end

        % Menu selected function: FrameKeyMenu
        function FrameKeyMenuSelected(app, event)
            app.Imseleket = "FrameKey";
            dispkeyimwahl(app)
        end

        % Menu selected function: FrameKeyMaskMenu
        function FrameKeyMaskMenuSelected(app, event)
            app.Imseleket = "FrameKeyMask";
            dispkeyimwahl(app)
        end

        % Menu selected function: FrameRotMenu
        function FrameRotMenuSelected(app, event)
            app.Imseleket = "FrameRot";
            dispkeyimwahl(app)
        end

        % Value changed function: RunButton
        function RunButtonValueChanged(app, event)
            calc(app)
        end

        % Value changed function: resEditField
        function resEditFieldValueChanged(app, event)
            app.Key(1).res = app.resEditField.Value;
            app.Key(2).res = app.resEditField.Value;
            app.Key(3).res = app.resEditField.Value;
            app.Key(4).res = app.resEditField.Value;
            app.Key(5).res = app.resEditField.Value;
            
            calc(app)
        end

        % Value changed function: closeMaskConstantEditField
        function closeMaskConstantEditFieldValueChanged(app, event)
            app.Key(1).closeMaskConstant = app.closeMaskConstantEditField.Value;
            app.Key(2).closeMaskConstant = app.closeMaskConstantEditField.Value;
            app.Key(3).closeMaskConstant = app.closeMaskConstantEditField.Value;
            app.Key(4).closeMaskConstant = app.closeMaskConstantEditField.Value;
            app.Key(5).closeMaskConstant = app.closeMaskConstantEditField.Value;
            
            calc(app)
        end

        % Menu selected function: RunMenu
        function RunMenuSelected(app, event)
            calc(app);
        end

        % Menu selected function: FrameSobelMenu
        function FrameSobelMenuSelected(app, event)
            app.Imseleket = "FrameSobel";
            dispkeyimwahl(app)
        end

        % Menu selected function: FrameLabelMenu
        function FrameLabelMenuSelected(app, event)
            app.Imseleket = "FrameLabel";
            dispkeyimwahl(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Auswahlbild1 = uiaxes(app.UIFigure);
            title(app.Auswahlbild1, 'Title')
            xlabel(app.Auswahlbild1, 'X')
            ylabel(app.Auswahlbild1, 'Y')
            zlabel(app.Auswahlbild1, 'Z')
            app.Bild1 = uiaxes(app.UIFigure);
            title(app.Bild1, 'Bild1')
            xlabel(app.Bild1, 'X')
            ylabel(app.Bild1, 'Y')
            zlabel(app.Bild1, 'Z')
            app.Auswahlbild2 = uiaxes(app.UIFigure);
            title(app.Auswahlbild2, 'Title')
            xlabel(app.Auswahlbild2, 'X')
            ylabel(app.Auswahlbild2, 'Y')
            zlabel(app.Auswahlbild2, 'Z')
            app.Bild2 = uiaxes(app.UIFigure);
            title(app.Bild2, 'Bild2')
            xlabel(app.Bild2, 'X')
            ylabel(app.Bild2, 'Y')
            zlabel(app.Bild2, 'Z')
            app.Auswahlbild3 = uiaxes(app.UIFigure);
            title(app.Auswahlbild3, 'Title')
            xlabel(app.Auswahlbild3, 'X')
            ylabel(app.Auswahlbild3, 'Y')
            zlabel(app.Auswahlbild3, 'Z')
            app.Bild3 = uiaxes(app.UIFigure);
            title(app.Bild3, 'Bild3')
            xlabel(app.Bild3, 'X')
            ylabel(app.Bild3, 'Y')
            zlabel(app.Bild3, 'Z')
            app.Auswahlbild4 = uiaxes(app.UIFigure);
            title(app.Auswahlbild4, 'Title')
            xlabel(app.Auswahlbild4, 'X')
            ylabel(app.Auswahlbild4, 'Y')
            zlabel(app.Auswahlbild4, 'Z')
            app.Bild4 = uiaxes(app.UIFigure);
            title(app.Bild4, 'Bild4')
            xlabel(app.Bild4, 'X')
            ylabel(app.Bild4, 'Y')
            zlabel(app.Bild4, 'Z')
            app.Auswahlbild5 = uiaxes(app.UIFigure);
            title(app.Auswahlbild5, 'Title')
            xlabel(app.Auswahlbild5, 'X')
            ylabel(app.Auswahlbild5, 'Y')
            zlabel(app.Auswahlbild5, 'Z')
            app.Bild5 = uiaxes(app.UIFigure);
            title(app.Bild5, 'Bild5')
            xlabel(app.Bild5, 'X')
            ylabel(app.Bild5, 'Y')
            zlabel(app.Bild5, 'Z')
            app.keyProfile1 = uiaxes(app.UIFigure);
            title(app.keyProfile1, 'keyProfile 1')
            xlabel(app.keyProfile1, 'X')
            ylabel(app.keyProfile1, 'Y')
            zlabel(app.keyProfile1, 'Z')
            app.keyProfile2 = uiaxes(app.UIFigure);
            title(app.keyProfile2, 'keyProfile 2')
            xlabel(app.keyProfile2, 'X')
            ylabel(app.keyProfile2, 'Y')
            zlabel(app.keyProfile2, 'Z')
            app.keyProfile3 = uiaxes(app.UIFigure);
            title(app.keyProfile3, 'keyProfile 3')
            xlabel(app.keyProfile3, 'X')
            ylabel(app.keyProfile3, 'Y')
            zlabel(app.keyProfile3, 'Z')
            app.keyProfile4 = uiaxes(app.UIFigure);
            title(app.keyProfile4, 'keyProfile 4')
            xlabel(app.keyProfile4, 'X')
            ylabel(app.keyProfile4, 'Y')
            zlabel(app.keyProfile4, 'Z')
            app.keyProfile5 = uiaxes(app.UIFigure);
            title(app.keyProfile5, 'keyProfile 5')
            xlabel(app.keyProfile5, 'X')
            ylabel(app.keyProfile5, 'Y')
            zlabel(app.keyProfile5, 'Z')
            app.ID1EditFieldLabel = uilabel(app.UIFigure);
            app.ID1EditFieldLabel.Text = 'ID1';
            app.ID1EditField = uieditfield(app.UIFigure, 'numeric');
            app.ID1EditField.ValueDisplayFormat = '%.0f';
            app.ID1EditField.Editable = 'off';
            app.ID2EditFieldLabel = uilabel(app.UIFigure);
            app.ID2EditFieldLabel.Text = 'ID2';
            app.ID2EditField = uieditfield(app.UIFigure, 'numeric');
            app.ID2EditField.ValueDisplayFormat = '%.0f';
            app.ID2EditField.Editable = 'off';
            app.ID3EditFieldLabel = uilabel(app.UIFigure);
            app.ID3EditFieldLabel.Text = 'ID3';
            app.ID3EditField = uieditfield(app.UIFigure, 'numeric');
            app.ID3EditField.ValueDisplayFormat = '%.0f';
            app.ID3EditField.Editable = 'off';
            app.ID4EditFieldLabel = uilabel(app.UIFigure);
            app.ID4EditFieldLabel.Text = 'ID4';
            app.ID4EditField = uieditfield(app.UIFigure, 'numeric');
            app.ID4EditField.ValueDisplayFormat = '%.0f';
            app.ID4EditField.Editable = 'off';
            app.ID5EditFieldLabel = uilabel(app.UIFigure);
            app.ID5EditFieldLabel.Text = 'ID5';
            app.ID5EditField = uieditfield(app.UIFigure, 'numeric');
            app.ID5EditField.ValueDisplayFormat = '%.0f';
            app.ID5EditField.Editable = 'off';
            app.Bild1EditFieldLabel = uilabel(app.UIFigure);
            app.Bild1EditFieldLabel.Text = 'Bild 1';
            app.Bild1EditField = uieditfield(app.UIFigure, 'text');
            app.Bild1EditField.Value = 'Schlussel3.jpg';
            app.Bild2EditFieldLabel = uilabel(app.UIFigure);
            app.Bild2EditFieldLabel.Text = 'Bild 2';
            app.Bild2EditField = uieditfield(app.UIFigure, 'text');
            app.Bild2EditField.Value = 'Schlussel3.jpg';
            app.Bild3EditFieldLabel = uilabel(app.UIFigure);
            app.Bild3EditFieldLabel.Text = 'Bild 3';
            app.Bild3EditField = uieditfield(app.UIFigure, 'text');
            app.Bild3EditField.Value = 'Schlussel3.jpg';
            app.Bild4EditFieldLabel = uilabel(app.UIFigure);
            app.Bild4EditFieldLabel.Text = 'Bild 4';
            app.Bild4EditField = uieditfield(app.UIFigure, 'text');
            app.Bild4EditField.Value = 'Schlussel3.jpg';
            app.Bild5EditFieldLabel = uilabel(app.UIFigure);
            app.Bild5EditFieldLabel.Text = 'Bild 5';
            app.Bild5EditField = uieditfield(app.UIFigure, 'text');
            app.Bild5EditField.Value = 'Schlussel3.jpg';
            app.resEditFieldLabel = uilabel(app.UIFigure);
            app.resEditFieldLabel.Text = 'res';
            app.resEditField = uieditfield(app.UIFigure, 'numeric');
            app.resEditField.ValueChangedFcn = createCallbackFcn(app, @resEditFieldValueChanged, true);
            app.closeMaskConstantEditFieldLabel = uilabel(app.UIFigure);
            app.closeMaskConstantEditFieldLabel.Text = 'closeMaskConstant';
            app.closeMaskConstantEditField = uieditfield(app.UIFigure, 'numeric');
            app.closeMaskConstantEditField.ValueChangedFcn = createCallbackFcn(app, @closeMaskConstantEditFieldValueChanged, true);
            app.closeMaskConstant2EditFieldLabel = uilabel(app.UIFigure);
            app.closeMaskConstant2EditFieldLabel.Text = 'closeMaskConstant2';
            app.closeMaskConstant2EditField = uieditfield(app.UIFigure, 'numeric');
            app.theta1EditFieldLabel = uilabel(app.UIFigure);
            app.theta1EditFieldLabel.Text = 'theta1';
            app.theta1EditField = uieditfield(app.UIFigure, 'numeric');
            app.theta1EditField.Editable = 'off';
            app.alpha1EditFieldLabel = uilabel(app.UIFigure);
            app.alpha1EditFieldLabel.Text = 'alpha1';
            app.alpha1EditField = uieditfield(app.UIFigure, 'numeric');
            app.alpha1EditField.Editable = 'off';
            app.k_len1EditFieldLabel = uilabel(app.UIFigure);
            app.k_len1EditFieldLabel.Text = 'k_len1';
            app.k_len1EditField = uieditfield(app.UIFigure, 'numeric');
            app.k_len1EditField.Editable = 'off';
            app.RunButton = uibutton(app.UIFigure, 'state');
            app.RunButton.ValueChangedFcn = createCallbackFcn(app, @RunButtonValueChanged, true);
            app.RunButton.Text = 'Run';
            app.theta2EditFieldLabel = uilabel(app.UIFigure);
            app.theta2EditFieldLabel.Text = 'theta2';
            app.theta2EditField = uieditfield(app.UIFigure, 'numeric');
            app.theta2EditField.Editable = 'off';
            app.alpha2EditFieldLabel = uilabel(app.UIFigure);
            app.alpha2EditFieldLabel.Text = 'alpha2';
            app.alpha2EditField = uieditfield(app.UIFigure, 'numeric');
            app.alpha2EditField.Editable = 'off';
            app.k_len2EditFieldLabel = uilabel(app.UIFigure);
            app.k_len2EditFieldLabel.Text = 'k_len2';
            app.k_len2EditField = uieditfield(app.UIFigure, 'numeric');
            app.k_len2EditField.Editable = 'off';
            app.theta3EditFieldLabel = uilabel(app.UIFigure);
            app.theta3EditFieldLabel.Text = 'theta3';
            app.theta3EditField = uieditfield(app.UIFigure, 'numeric');
            app.theta3EditField.Editable = 'off';
            app.alpha3EditFieldLabel = uilabel(app.UIFigure);
            app.alpha3EditFieldLabel.Text = 'alpha3';
            app.alpha3EditField = uieditfield(app.UIFigure, 'numeric');
            app.alpha3EditField.Editable = 'off';
            app.k_len3EditFieldLabel = uilabel(app.UIFigure);
            app.k_len3EditFieldLabel.Text = 'k_len3';
            app.k_len3EditField = uieditfield(app.UIFigure, 'numeric');
            app.k_len3EditField.Editable = 'off';
            app.theta4EditFieldLabel = uilabel(app.UIFigure);
            app.theta4EditFieldLabel.Text = 'theta4';
            app.theta4EditField = uieditfield(app.UIFigure, 'numeric');
            app.theta4EditField.Editable = 'off';
            app.alpha4EditFieldLabel = uilabel(app.UIFigure);
            app.alpha4EditFieldLabel.Text = 'alpha4';
            app.alpha4EditField = uieditfield(app.UIFigure, 'numeric');
            app.alpha4EditField.Editable = 'off';
            app.k_len4EditFieldLabel = uilabel(app.UIFigure);
            app.k_len4EditFieldLabel.Text = 'k_len4';
            app.k_len4EditField = uieditfield(app.UIFigure, 'numeric');
            app.k_len4EditField.Editable = 'off';
            app.theta5EditFieldLabel = uilabel(app.UIFigure);
            app.theta5EditFieldLabel.Text = 'theta5';
            app.theta5EditField = uieditfield(app.UIFigure, 'numeric');
            app.theta5EditField.Editable = 'off';
            app.alpha5EditFieldLabel = uilabel(app.UIFigure);
            app.alpha5EditFieldLabel.Text = 'alpha5';
            app.alpha5EditField = uieditfield(app.UIFigure, 'numeric');
            app.alpha5EditField.Editable = 'off';
            app.k_len5EditFieldLabel = uilabel(app.UIFigure);
            app.k_len5EditFieldLabel.Text = 'k_len5';
            app.k_len5EditField = uieditfield(app.UIFigure, 'numeric');
            app.k_len5EditField.Editable = 'off';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.RunMenu = uimenu(app.ContextMenu);
            app.RunMenu.MenuSelectedFcn = createCallbackFcn(app, @RunMenuSelected, true);
            app.RunMenu.Text = 'Run';
            app.PlotndernMenu = uimenu(app.ContextMenu);
            app.PlotndernMenu.Text = '2. Plot ändern';
            app.FrameCutMaskMenu = uimenu(app.PlotndernMenu);
            app.FrameCutMaskMenu.MenuSelectedFcn = createCallbackFcn(app, @FrameCutMaskMenuSelected, true);
            app.FrameCutMaskMenu.Text = 'FrameCutMask';
            app.FrameCutMenu = uimenu(app.PlotndernMenu);
            app.FrameCutMenu.MenuSelectedFcn = createCallbackFcn(app, @FrameCutMenuSelected, true);
            app.FrameCutMenu.Text = 'FrameCut';
            app.FrameKeyMenu = uimenu(app.PlotndernMenu);
            app.FrameKeyMenu.MenuSelectedFcn = createCallbackFcn(app, @FrameKeyMenuSelected, true);
            app.FrameKeyMenu.Text = 'FrameKey';
            app.FrameKeyMaskMenu = uimenu(app.PlotndernMenu);
            app.FrameKeyMaskMenu.MenuSelectedFcn = createCallbackFcn(app, @FrameKeyMaskMenuSelected, true);
            app.FrameKeyMaskMenu.Text = 'FrameKeyMask';
            app.FrameRotMenu = uimenu(app.PlotndernMenu);
            app.FrameRotMenu.MenuSelectedFcn = createCallbackFcn(app, @FrameRotMenuSelected, true);
            app.FrameRotMenu.Text = 'FrameRot';
            app.FrameSobelMenu = uimenu(app.PlotndernMenu);
            app.FrameSobelMenu.MenuSelectedFcn = createCallbackFcn(app, @FrameSobelMenuSelected, true);
            app.FrameSobelMenu.Text = 'FrameSobel';
            app.FrameLabelMenu = uimenu(app.PlotndernMenu);
            app.FrameLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @FrameLabelMenuSelected, true);
            app.FrameLabelMenu.Text = 'FrameLabel';
            % Assign app.ContextMenu
            app.UIFigure.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Experiment

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end