classdef Projekt_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        MainTab                   matlab.ui.container.Tab
        IDEditField               matlab.ui.control.EditField
        IDEditFieldLabel          matlab.ui.control.Label
        RefreshButton             matlab.ui.control.Button
        UITable                   matlab.ui.control.Table
        PictureButton             matlab.ui.control.StateButton
        VideoButton               matlab.ui.control.StateButton
        StartButton               matlab.ui.control.StateButton
        UIAxes_KeyProfile         matlab.ui.control.UIAxes
        UIAxes_came               matlab.ui.control.UIAxes
        DebugTab                  matlab.ui.container.Tab
        FramerateLabel            matlab.ui.control.Label
        IDLabel                   matlab.ui.control.Label
        k_lenLabel                matlab.ui.control.Label
        aLabel                    matlab.ui.control.Label
        Closing1DivisorLabel      matlab.ui.control.Label
        ResolutionLabel           matlab.ui.control.Label
        Closing2DivisorLabel      matlab.ui.control.Label
        teterLabel                matlab.ui.control.Label
        OnlyValidLabel            matlab.ui.control.Label
        CheckBox                  matlab.ui.control.CheckBox
        keyIdEditField            matlab.ui.control.EditField
        Lamp                      matlab.ui.control.Lamp
        FramerateEditField        matlab.ui.control.NumericEditField
        k_lenEditField            matlab.ui.control.NumericEditField
        EditField_2               matlab.ui.control.NumericEditField
        EditField                 matlab.ui.control.NumericEditField
        Closing2DivisorEditField  matlab.ui.control.NumericEditField
        Closing1DivisorEditField  matlab.ui.control.NumericEditField
        ResolutionEditField       matlab.ui.control.NumericEditField
        PictureButton_2           matlab.ui.control.StateButton
        VideoButton_2             matlab.ui.control.StateButton
        StartButton_2             matlab.ui.control.StateButton
        EdgeDetect                matlab.ui.control.UIAxes
        keyProfile                matlab.ui.control.UIAxes
        FrameKeyMask_FrameKey     matlab.ui.control.UIAxes
        FrameRot                  matlab.ui.control.UIAxes
        FrameCut                  matlab.ui.control.UIAxes
        Cam_Debug                 matlab.ui.control.UIAxes
        ContextMenu_FrameKeyMask_FrameKey  matlab.ui.container.ContextMenu
        ExternalPlotMenu_7        matlab.ui.container.Menu
        EdgeDetektMenu            matlab.ui.container.Menu
        ProfileMenu               matlab.ui.container.Menu
        FrameKeyMask_FrameKeyMenu_Label  matlab.ui.container.Menu
        MaskMenu_2                matlab.ui.container.Menu
        KeyMenu                   matlab.ui.container.Menu
        LabelMenu_2               matlab.ui.container.Menu
        RotationMenu              matlab.ui.container.Menu
        SegmentationIMenu         matlab.ui.container.Menu
        SegmentationIIMenu        matlab.ui.container.Menu
        ExtraPlotMenu             matlab.ui.container.Menu
        MaskMenu                  matlab.ui.container.Menu
        KeyMenu_2                 matlab.ui.container.Menu
        LabelMenu                 matlab.ui.container.Menu
    end

    
    properties (Access = private)
        Debug

        Key

        picture_cam
        camfind = true
        cam

        res
        closeMaskConstant
        closeMaskConstant2

        pauseTime = 1

        showMask = 1
        frame

        Debugging = false
    end
    
    methods (Access = private)
        
        function camera(app)
            
             while app.StartButton.Value==1

                try
                    app.frame = snapshot(app.cam);

                    %app.frame=imread("Demo.jpg");
                catch

                    f = msgbox("camera timeout");
                    f.Name = "Error";
                    app.StartButton.Value = 0;
                    app.StartButton_2.Value = 0;

                    return
                end

                %Übergabe Frame
                app.Key.Frame=app.frame;
                
                FrameBackground = ones(size(app.Key.Frame,[1 2])+[20 20]);
                FrameBackground(11:(size(FrameBackground,1)-10),11:(size(FrameBackground,2)-10),:) = 0;

                Frame = zeros(size(app.Key.Frame)+[20 20 0]);
                Frame(11:(size(FrameBackground,1)-10),11:(size(FrameBackground,2)-10),:) = app.Key.Frame;


                Frame = Frame./255;

                if app.Key.isValid
                    FrameBackground = imoverlay(Frame,FrameBackground,"green");
                else
                    FrameBackground = imoverlay(Frame,FrameBackground,"red");
                end
                

                imshow(FrameBackground,[],"parent",app.UIAxes_came)

                if app.Debugging == 1
                    imshow(FrameBackground,[],"parent",app.Cam_Debug)
                end
                
                if app.PictureButton.Value == 1
                    app.Lamp.Color = [0 0 1];
                    app.Lamp.Enable = "on";

                    app.Lamp.Enable = "off";
                    
                elseif app.VideoButton.Value == 1

                    ToggleLamp(app)


                    %Übergabe Frame
                    app.Key.Frame=app.frame;
                end

                if (app.VideoButton.Value + app.PictureButton.Value > 0)
                    try
                        app.Key = app.Key.calculate();
                    catch e
                        f=msgbox("Error calculating: " + e.message);
                        f.Name = "Error";
                    end

                    if app.Debug   
                        DebugVis(app)
                    end
    	            
                    if app.Key.isValid
    
                        app.IDEditField.Value = app.Key.ID;
    
                        plot(app.Key.keyProfile,"parent",app.UIAxes_KeyProfile)
                        
                        extr=app.Key.extr;
                        hold(app.UIAxes_KeyProfile,"on")
                        plot(extr(:,1),extr(:,2),"*","parent",app.UIAxes_KeyProfile)
                        hold(app.UIAxes_KeyProfile,"off")
                        
                    end
                    
                    % Reset Button Value
                    app.PictureButton.Value = 0;
                    app.PictureButton_2.Value = 0;
                end
                

                pause(app.pauseTime)
             end
        end
        
        function DebugOnOff(app)
            if app.Debugging
                app.keyIdEditField.Visible = "on";
                app.k_lenEditField.Visible = "on";
                app.EditField_2.Visible = "on";
                app.EditField.Visible = "on";
                app.Closing2DivisorEditField.Visible = "on";
                app.Closing1DivisorEditField.Visible = "on";
                app.ResolutionEditField.Visible = "on";
                app.PictureButton_2.Visible = "on";
                app.VideoButton_2.Visible = "on";
                app.StartButton_2.Visible = "on";
                app.keyProfile.Visible = "on";
                app.FrameKeyMask_FrameKey.Visible = "on";
                app.FrameRot.Visible = "on";
                app.FrameCut.Visible = "on";
                app.Cam_Debug.Visible = "on";
                app.FramerateEditField.Visible = "on";
                app.EdgeDetect.Visible = "on";
                app.Lamp.Visible = "on";
                app.CheckBox.Visible = "on";
                app.FramerateLabel.Visible = "on";
                app.IDLabel.Visible = "on";
                app.k_lenLabel.Visible = "on";
                app.aLabel.Visible = "on";
                app.Closing1DivisorLabel.Visible = "on";
                app.ResolutionLabel.Visible = "on";
                app.Closing2DivisorLabel.Visible = "on";
                app.teterLabel.Visible = "on";
                app.OnlyValidLabel.Visible = "on";

                app.Debug = true;
            else
                app.keyIdEditField.Visible = "off";
                app.k_lenEditField.Visible = "off";
                app.EditField_2.Visible = "off";
                app.EditField.Visible = "off";
                app.Closing2DivisorEditField.Visible = "off";
                app.Closing1DivisorEditField.Visible = "off";
                app.ResolutionEditField.Visible = "off";
                app.PictureButton_2.Visible = "off";
                app.VideoButton_2.Visible = "off";
                app.StartButton_2.Visible = "off";
                app.keyProfile.Visible = "off";
                app.FrameKeyMask_FrameKey.Visible = "off";
                app.FrameRot.Visible = "off";
                app.FrameCut.Visible = "off";
                app.Cam_Debug.Visible = "off";
                app.FramerateEditField.Visible = "off";
                app.EdgeDetect.Visible = "off";
                app.Lamp.Visible = "off";
                app.CheckBox.Visible = "off";
                app.FramerateLabel.Visible = "off";
                app.IDLabel.Visible = "off";
                app.k_lenLabel.Visible = "off";
                app.aLabel.Visible = "off";
                app.Closing1DivisorLabel.Visible = "off";
                app.ResolutionLabel.Visible = "off";
                app.Closing2DivisorLabel.Visible = "off";
                app.teterLabel.Visible = "off";
                app.OnlyValidLabel.Visible = "off";


                app.Debug = false;
            end
        end
        
        function DebugVis(app)
            % Show Images

            if app.CheckBox.Value
                if app.Key.isValid == false
                    return
                end  
            end


            if app.showMask == 1

                imshow(app.Key.FrameKeyMask,[],"parent",app.FrameKeyMask_FrameKey)

            elseif app.showMask == 3

                imshow(app.Key.FrameLabel,[],"parent",app.FrameKeyMask_FrameKey)

            else

                imshow(app.Key.FrameKey,[],"parent",app.FrameKeyMask_FrameKey)
                
            end

            imshow(app.Key.FrameRot,[],"parent",app.FrameRot)
            imshow(app.Key.FrameCut,[],"parent",app.FrameCut)
            imshow(app.Key.EdgeFrame,[],"parent",app.EdgeDetect)
                
            if (app.Key.isValid)
                extr=app.Key.extr;
    
                % Show Profile
                plot(app.Key.keyProfile,"parent",app.keyProfile)
                hold(app.keyProfile,"on")
                plot(extr(:,1),extr(:,2),"*","parent",app.keyProfile)
                hold(app.keyProfile,"off")
    
                % Plot all Parents
                app.keyIdEditField.Value = app.Key.ID;
                app.k_lenEditField.Value = app.Key.k_len;
                app.EditField_2.Value = app.Key.alpha;
                app.EditField.Value = app.Key.theta;
            end
        end
        
        function ToggleLamp(app)
            if app.Lamp.Enable
                app.Lamp.Enable = "off";
            else
                app.Lamp.Enable = "on";
            end
        end
        
        function Startup(app)
            if app.camfind
                app.StartButton.Enable = "on";
                app.StartButton_2.Enable = "on";
                app.VideoButton.Enable = "on";
                app.VideoButton_2.Enable = "on";
                app.PictureButton.Enable = "on";
                app.PictureButton_2.Enable = "on";
            else
                app.StartButton.Enable = "off";
                app.StartButton_2.Enable = "off";
                app.VideoButton.Enable = "off";
                app.VideoButton_2.Enable = "off";
                app.PictureButton.Enable = "off";
                app.PictureButton_2.Enable = "off";
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Key=idKey();

            app.res = app.Key.res;
            app.closeMaskConstant = app.Key.closeMaskConstant;
            app.closeMaskConstant2 = app.Key.closeMaskConstant2;

            app.ResolutionEditField.Value = app.Key.res;
            app.Closing1DivisorEditField.Value = app.Key.closeMaskConstant;
            app.Closing2DivisorEditField.Value = app.Key.closeMaskConstant2;

            app.FramerateEditField.Value = app.pauseTime;

            RefreshButtonPushed(app);
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            app.StartButton_2.Value = app.StartButton.Value;
            camera(app)
        end

        % Value changed function: VideoButton
        function VideoButtonValueChanged(app, event)
            app.VideoButton_2.Value = app.VideoButton.Value;
            app.Lamp.Color = [1,0,0];
            app.Lamp.Enable = "off";
        end

        % Value changed function: PictureButton
        function PictureButtonValueChanged(app, event)
            app.PictureButton_2.Value = app.PictureButton.Value;
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            clear cam

            camList=webcamlist;
            n=1:length(camList);
            tabcams=table(n',camList);
            app.UITable.Data=tabcams;

            try
                if (isempty(app.UITable.Selection))
                    app.cam=webcam(1);
                else
                    app.cam = webcam(app.UITable.Selection);
                end
                app.camfind = true;
                
                Startup(app);
            catch
                app.camfind = false;
                f = msgbox("No camera found");
                f.Name = "Error";
                
                Startup(app);
            end
        end

        % Value changed function: FramerateEditField
        function FramerateEditFieldValueChanged(app, event)
            app.pauseTime = 1/app.FramerateEditField.Value;
        end

        % Value changed function: ResolutionEditField
        function ResolutionEditFieldValueChanged(app, event)
            app.Key.res = app.ResolutionEditField.Value;
        end

        % Value changed function: Closing1DivisorEditField
        function Closing1DivisorEditFieldValueChanged(app, event)
            app.Key.closeMaskConstant = app.Closing1DivisorEditField.Value;
        end

        % Value changed function: Closing2DivisorEditField
        function Closing2DivisorEditFieldValueChanged(app, event)
            app.Key.closeMaskConstant2 = app.Closing2DivisorEditField.Value;
        end

        % Callback function
        function DebugOnOffButtonValueChanged(app, event)
            DebugOnOff(app)
        end

        % Value changed function: StartButton_2
        function StartButton_2ValueChanged(app, event)
            app.StartButton.Value = app.StartButton_2.Value;
            camera(app)
        end

        % Value changed function: VideoButton_2
        function VideoButton_2ValueChanged(app, event)
            app.VideoButton.Value = app.VideoButton_2.Value;
            app.Lamp.Color = [1 0 0];
            app.Lamp.Enable = "off";
        end

        % Value changed function: PictureButton_2
        function PictureButton_2ValueChanged(app, event)
            app.PictureButton.Value = app.PictureButton_2.Value;
            
        end

        % Button down function: Cam_Debug
        function Cam_DebugButtonDown(app, event)
            
        end

        % Menu selected function: MaskMenu
        function MaskMenuSelected(app, event)
            app.showMask = 1;
            DebugVis(app)
        end

        % Menu selected function: KeyMenu_2
        function KeyMenu_2Selected(app, event)
            app.showMask = 0;
            DebugVis(app)
        end

        % Menu selected function: ProfileMenu
        function ExternalPlot_keyProfile(app, event)
            % keyProfile
            imshow(app.Key.keyProfile,[])
        end

        % Button down function: FrameRot
        function FrameRotButtonDown(app, event)
            
        end

        % Menu selected function: MaskMenu_2
        function ExternalPlot_FrameKeyMask(app, event)
            % FrameKeyMask
            imshow(app.Key.FrameKeyMask,[])
        end

        % Menu selected function: SegmentationIMenu
        function ExternalPlot_FrameCut(app, event)
            % FrameCut

            imshow(app.Key.FrameCut,[])
        end

        % Menu selected function: SegmentationIIMenu
        function ExternalPlot_FrameCutMask(app, event)
            % FrameCutMask

            imshow(app.Key.FrameCutMask,[])
        end

        % Menu selected function: RotationMenu
        function ExternalPlot_FrameRot(app, event)
            % FrameRot

            imshow(app.Key.FrameRot,[])
        end

        % Menu selected function: EdgeDetektMenu
        function ExternalPlot_EdgeDetekt(app, event)
            % Sobel
            
            imshow(app.Key.EdgeFrame,[])
        end

        % Menu selected function: KeyMenu
        function ExternalPlot_FrameKey(app, event)
            % FrameKey

            imshow(app.Key.FrameKey,[])
        end

        % Menu selected function: LabelMenu_2
        function LabelMenu_2Selected(app, event)
            % Label

            imshow(app.Key.FrameLabel,[])
        end

        % Menu selected function: LabelMenu
        function LabelMenuSelected(app, event)
            app.showMask = 3;
            DebugVis(app) 
        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            selection = app.UITable.Selection;
            app.cam = webcam(selection);
        end

        % Button down function: DebugTab
        function DebugTabButtonDown(app, event)
            app.Debugging = true;
            DebugOnOff(app)
        end

        % Button down function: MainTab
        function MainTabButtonDown(app, event)
            app.Debugging = false;
            DebugOnOff(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.MainTab.ButtonDownFcn = createCallbackFcn(app, @MainTabButtonDown, true);
            app.UIAxes_came = uiaxes(app.MainTab);
            title(app.UIAxes_came, 'Camera')
            app.UIAxes_came.XTick = [];
            app.UIAxes_came.YTick = [];
            colormap(app.UIAxes_came, 'parula')
            app.UIAxes_KeyProfile = uiaxes(app.MainTab);
            title(app.UIAxes_KeyProfile, 'Profile')
            app.UIAxes_KeyProfile.XTick = [];
            app.UIAxes_KeyProfile.YTick = [];
            colormap(app.UIAxes_KeyProfile, 'parula')
            app.StartButton = uibutton(app.MainTab, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.VideoButton = uibutton(app.MainTab, 'state');
            app.VideoButton.ValueChangedFcn = createCallbackFcn(app, @VideoButtonValueChanged, true);
            app.VideoButton.Enable = 'off';
            app.VideoButton.Text = 'Video';
            app.PictureButton = uibutton(app.MainTab, 'state');
            app.PictureButton.ValueChangedFcn = createCallbackFcn(app, @PictureButtonValueChanged, true);
            app.PictureButton.Enable = 'off';
            app.PictureButton.Text = 'Picture';
            app.UITable = uitable(app.MainTab);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.UITable.SelectionType = 'row';
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.UITable.Multiselect = 'off';
            app.RefreshButton = uibutton(app.MainTab, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.IDEditFieldLabel = uilabel(app.MainTab);
            app.IDEditFieldLabel.Text = 'ID';
            app.IDEditField = uieditfield(app.MainTab, 'text');
            app.IDEditField.Editable = 'off';
            app.DebugTab = uitab(app.TabGroup);
            app.DebugTab.Title = 'Debug';
            app.DebugTab.ButtonDownFcn = createCallbackFcn(app, @DebugTabButtonDown, true);
            app.Cam_Debug = uiaxes(app.DebugTab);
            title(app.Cam_Debug, 'Preprocessing')
            app.Cam_Debug.XTick = [];
            app.Cam_Debug.YTick = [];
            app.Cam_Debug.ButtonDownFcn = createCallbackFcn(app, @Cam_DebugButtonDown, true);
            app.Cam_Debug.Visible = 'off';
            colormap(app.Cam_Debug, 'parula')
            app.FrameCut = uiaxes(app.DebugTab);
            title(app.FrameCut, 'Segmentation')
            app.FrameCut.XTick = [];
            app.FrameCut.YTick = [];
            app.FrameCut.Visible = 'off';
            colormap(app.FrameCut, 'parula')
            app.FrameRot = uiaxes(app.DebugTab);
            title(app.FrameRot, 'Rotation')
            app.FrameRot.XTick = [];
            app.FrameRot.YTick = [];
            app.FrameRot.ButtonDownFcn = createCallbackFcn(app, @FrameRotButtonDown, true);
            app.FrameRot.Visible = 'off';
            colormap(app.FrameRot, 'parula')
            app.FrameKeyMask_FrameKey = uiaxes(app.DebugTab);
            title(app.FrameKeyMask_FrameKey, 'Extra Plot')
            app.FrameKeyMask_FrameKey.XTick = [];
            app.FrameKeyMask_FrameKey.YTick = [];
            app.FrameKeyMask_FrameKey.Visible = 'off';
            colormap(app.FrameKeyMask_FrameKey, 'parula')
            app.keyProfile = uiaxes(app.DebugTab);
            title(app.keyProfile, 'Profile')
            app.keyProfile.XTick = [];
            app.keyProfile.YTick = [];
            app.keyProfile.Visible = 'off';
            colormap(app.keyProfile, 'parula')
            app.EdgeDetect = uiaxes(app.DebugTab);
            title(app.EdgeDetect, 'Edge Detection')
            app.EdgeDetect.XTick = [];
            app.EdgeDetect.YTick = [];
            app.EdgeDetect.Visible = 'off';
            colormap(app.EdgeDetect, 'parula')
            app.StartButton_2 = uibutton(app.DebugTab, 'state');
            app.StartButton_2.ValueChangedFcn = createCallbackFcn(app, @StartButton_2ValueChanged, true);
            app.StartButton_2.Enable = 'off';
            app.StartButton_2.Visible = 'off';
            app.StartButton_2.Text = 'Start';
            app.VideoButton_2 = uibutton(app.DebugTab, 'state');
            app.VideoButton_2.ValueChangedFcn = createCallbackFcn(app, @VideoButton_2ValueChanged, true);
            app.VideoButton_2.Enable = 'off';
            app.VideoButton_2.Visible = 'off';
            app.VideoButton_2.Text = 'Video';
            app.PictureButton_2 = uibutton(app.DebugTab, 'state');
            app.PictureButton_2.ValueChangedFcn = createCallbackFcn(app, @PictureButton_2ValueChanged, true);
            app.PictureButton_2.Enable = 'off';
            app.PictureButton_2.Visible = 'off';
            app.PictureButton_2.Text = 'Picture';
            app.ResolutionEditField = uieditfield(app.DebugTab, 'numeric');
            app.ResolutionEditField.ValueChangedFcn = createCallbackFcn(app, @ResolutionEditFieldValueChanged, true);
            app.ResolutionEditField.Visible = 'off';
            app.Closing1DivisorEditField = uieditfield(app.DebugTab, 'numeric');
            app.Closing1DivisorEditField.ValueChangedFcn = createCallbackFcn(app, @Closing1DivisorEditFieldValueChanged, true);
            app.Closing1DivisorEditField.Visible = 'off';
            app.Closing2DivisorEditField = uieditfield(app.DebugTab, 'numeric');
            app.Closing2DivisorEditField.ValueChangedFcn = createCallbackFcn(app, @Closing2DivisorEditFieldValueChanged, true);
            app.Closing2DivisorEditField.Visible = 'off';
            app.EditField = uieditfield(app.DebugTab, 'numeric');
            app.EditField.Editable = 'off';
            app.EditField.Visible = 'off';
            app.EditField_2 = uieditfield(app.DebugTab, 'numeric');
            app.EditField_2.Editable = 'off';
            app.EditField_2.Visible = 'off';
            app.k_lenEditField = uieditfield(app.DebugTab, 'numeric');
            app.k_lenEditField.Editable = 'off';
            app.k_lenEditField.Visible = 'off';
            app.FramerateEditField = uieditfield(app.DebugTab, 'numeric');
            app.FramerateEditField.ValueChangedFcn = createCallbackFcn(app, @FramerateEditFieldValueChanged, true);
            app.FramerateEditField.Visible = 'off';
            app.Lamp = uilamp(app.DebugTab);
            app.Lamp.Visible = 'off';
            app.keyIdEditField = uieditfield(app.DebugTab, 'text');
            app.keyIdEditField.Editable = 'off';
            app.keyIdEditField.Visible = 'off';
            app.CheckBox = uicheckbox(app.DebugTab);
            app.CheckBox.Visible = 'off';
            app.CheckBox.Text = '';
            app.CheckBox.Value = true;
            app.OnlyValidLabel = uilabel(app.DebugTab);
            app.OnlyValidLabel.Visible = 'off';
            app.OnlyValidLabel.Text = 'Only valid';
            app.teterLabel = uilabel(app.DebugTab);
            app.teterLabel.Visible = 'off';
            app.teterLabel.Text = 'θ';
            app.Closing2DivisorLabel = uilabel(app.DebugTab);
            app.Closing2DivisorLabel.Visible = 'off';
            app.Closing2DivisorLabel.Text = 'Closing 2 (Divisor)';
            app.ResolutionLabel = uilabel(app.DebugTab);
            app.ResolutionLabel.Visible = 'off';
            app.ResolutionLabel.Text = 'Resolution';
            app.Closing1DivisorLabel = uilabel(app.DebugTab);
            app.Closing1DivisorLabel.Visible = 'off';
            app.Closing1DivisorLabel.Text = 'Closing 1 (Divisor)';
            app.aLabel = uilabel(app.DebugTab);
            app.aLabel.Visible = 'off';
            app.aLabel.Text = 'α';
            app.k_lenLabel = uilabel(app.DebugTab);
            app.k_lenLabel.Visible = 'off';
            app.k_lenLabel.Text = 'k_len';
            app.IDLabel = uilabel(app.DebugTab);
            app.IDLabel.Visible = 'off';
            app.IDLabel.Text = 'ID';
            app.FramerateLabel = uilabel(app.DebugTab);
            app.FramerateLabel.Visible = 'off';
            app.FramerateLabel.Text = 'Framerate:';
            app.ContextMenu_FrameKeyMask_FrameKey = uicontextmenu(app.UIFigure);
            app.ExternalPlotMenu_7 = uimenu(app.ContextMenu_FrameKeyMask_FrameKey);
            app.ExternalPlotMenu_7.Text = 'External Plot';
            app.EdgeDetektMenu = uimenu(app.ExternalPlotMenu_7);
            app.EdgeDetektMenu.MenuSelectedFcn = createCallbackFcn(app, @ExternalPlot_EdgeDetekt, true);
            app.EdgeDetektMenu.Text = 'Edge Detection';
            app.ProfileMenu = uimenu(app.ExternalPlotMenu_7);
            app.ProfileMenu.MenuSelectedFcn = createCallbackFcn(app, @ExternalPlot_keyProfile, true);
            app.ProfileMenu.Text = 'Profile';
            app.FrameKeyMask_FrameKeyMenu_Label = uimenu(app.ExternalPlotMenu_7);
            app.FrameKeyMask_FrameKeyMenu_Label.Text = 'Extra Plot';
            app.MaskMenu_2 = uimenu(app.FrameKeyMask_FrameKeyMenu_Label);
            app.MaskMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ExternalPlot_FrameKeyMask, true);
            app.MaskMenu_2.Text = 'Mask';
            app.KeyMenu = uimenu(app.FrameKeyMask_FrameKeyMenu_Label);
            app.KeyMenu.MenuSelectedFcn = createCallbackFcn(app, @ExternalPlot_FrameKey, true);
            app.KeyMenu.Text = 'Key';
            app.LabelMenu_2 = uimenu(app.FrameKeyMask_FrameKeyMenu_Label);
            app.LabelMenu_2.MenuSelectedFcn = createCallbackFcn(app, @LabelMenu_2Selected, true);
            app.LabelMenu_2.Text = 'Label';
            app.RotationMenu = uimenu(app.ExternalPlotMenu_7);
            app.RotationMenu.MenuSelectedFcn = createCallbackFcn(app, @ExternalPlot_FrameRot, true);
            app.RotationMenu.Text = 'Rotation';
            app.SegmentationIMenu = uimenu(app.ExternalPlotMenu_7);
            app.SegmentationIMenu.MenuSelectedFcn = createCallbackFcn(app, @ExternalPlot_FrameCut, true);
            app.SegmentationIMenu.Text = 'Segmentation (I)';
            app.SegmentationIIMenu = uimenu(app.ExternalPlotMenu_7);
            app.SegmentationIIMenu.MenuSelectedFcn = createCallbackFcn(app, @ExternalPlot_FrameCutMask, true);
            app.SegmentationIIMenu.Text = 'Segmentation (II)';
            app.ExtraPlotMenu = uimenu(app.ContextMenu_FrameKeyMask_FrameKey);
            app.ExtraPlotMenu.Text = 'Extra Plot';
            app.MaskMenu = uimenu(app.ExtraPlotMenu);
            app.MaskMenu.MenuSelectedFcn = createCallbackFcn(app, @MaskMenuSelected, true);
            app.MaskMenu.Text = 'Mask';
            app.KeyMenu_2 = uimenu(app.ExtraPlotMenu);
            app.KeyMenu_2.MenuSelectedFcn = createCallbackFcn(app, @KeyMenu_2Selected, true);
            app.KeyMenu_2.Text = 'Key';
            app.LabelMenu = uimenu(app.ExtraPlotMenu);
            app.LabelMenu.MenuSelectedFcn = createCallbackFcn(app, @LabelMenuSelected, true);
            app.LabelMenu.Text = 'Label';
            % Assign app.ContextMenu_FrameKeyMask_FrameKey
            app.DebugTab.ContextMenu = app.ContextMenu_FrameKeyMask_FrameKey;
            app.FrameKeyMask_FrameKey.ContextMenu = app.ContextMenu_FrameKeyMask_FrameKey;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Projekt_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end